classdef TrussesSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ResetButton                matlab.ui.control.Button
        SolveButton                matlab.ui.control.Button
        AddNewEdgesButton          matlab.ui.control.StateButton
        DeleteUnwantedEdgesButton  matlab.ui.control.StateButton
        AddPointsButton            matlab.ui.control.StateButton
        yTicksEditField            matlab.ui.control.EditField
        yTicksEditFieldLabel       matlab.ui.control.Label
        xTicksEditField            matlab.ui.control.EditField
        xTicksEditFieldLabel       matlab.ui.control.Label
        ConnectPointsButton        matlab.ui.control.Button
        AddLoadPanel               matlab.ui.container.Panel
        AddLoadButton              matlab.ui.control.Button
        OrientatonEditField        matlab.ui.control.NumericEditField
        OrientatonEditFieldLabel   matlab.ui.control.Label
        LoadEditField              matlab.ui.control.NumericEditField
        LoadEditFieldLabel         matlab.ui.control.Label
        AddSupportPanel            matlab.ui.container.Panel
        AddSupportButton           matlab.ui.control.Button
        OrientationDropDown        matlab.ui.control.DropDown
        OrientationDropDownLabel   matlab.ui.control.Label
        SupportTypeDropDown        matlab.ui.control.DropDown
        SupportTypeDropDownLabel   matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        X   = [];            % Description
        Y   = [];            % Description
        Xg  = [];            % Description
        Yg  = [];            % Description
        dx  = 0;             % Description
        dy  = 0;             % Description
        dl  = 0;             % Description
        Edges = [];          % Description
        Supports = {};       % Description
        SupportType = "";    % Description
        SupportOrient = "";  % Description
        SupportObjects = []; % Description
        Loads = {};          % Description
        LoadValue = 0;       % Description
        LoadOrient = 0;      % Description
        LoadObjects = [];    % Description
        ptID = 0;            % Description
        xpt = 0;             % Description
        ypt = 0;             % Description
        Hull = [];           % Description
        done = true;         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xTicksEditField
        function xTicksEditFieldValueChanged(app, event)
            cvalues = split(app.xTicksEditField.Value, ",");
            nvalues = arrayfun(@(i) str2double(cvalues{i}), 1:numel(cvalues));
            nvalues = cumsum([0,nvalues]);
            app.UIAxes.XTick = nvalues;
            xmin = min(nvalues);
            xmax = max(nvalues);
            xrange = xmax - xmin;
            app.dx = xrange/20;
            app.dl = hypot(app.dx, app.dy);
            app.UIAxes.XLim = [xmin - 10*app.dx, xmax + 10*app.dx];
            [xg, yg] = meshgrid(app.UIAxes.XTick, app.UIAxes.YTick);
            app.Xg = xg(:); app.Yg = yg(:); daspect(app.UIAxes, [1,1,1]);
        end

        % Value changed function: yTicksEditField
        function yTicksEditFieldValueChanged(app, event)
            cvalues = split(app.yTicksEditField.Value, ",");
            nvalues = arrayfun(@(i) str2double(cvalues{i}), 1:numel(cvalues));
            nvalues = cumsum([0,nvalues]);
            app.UIAxes.YTick = nvalues;
            ymin = min(nvalues);
            ymax = max(nvalues);
            yrange = ymax - ymin;
            app.dy = yrange/20;
            app.dl = hypot(app.dx, app.dy);
            app.UIAxes.YLim = [ymin - 10*app.dy, ymax + 10*app.dy];
            [xg, yg] = meshgrid(app.UIAxes.XTick, app.UIAxes.YTick);
            app.Xg = xg(:); app.Yg = yg(:); daspect(app.UIAxes, [1,1,1]);
        end

        % Value changed function: AddPointsButton
        function AddPointsButtonValueChanged(app, event)
            title(app.UIAxes, 'Click on the axis to add points')
            hold(app.UIAxes , "on");
            while(app.AddPointsButton.Value)
                roi = drawpoint(app.UIAxes); 
                xy = roi.Position;
                delete(app.UIAxes.Children(1));
                D = hypot(xy(1) - app.Xg, xy(2) - app.Yg);
                [minD, i] = min(D); x = app.Xg(i); y = app.Yg(i);
                if(minD < app.dl)
                    plot(app.UIAxes, x, y, 'ok'); 
                    app.X = [app.X; x]; app.Y = [app.Y; y]; 
                end
            end
            title(app.UIAxes, ''); 
        end

        % Button pushed function: ConnectPointsButton
        function ConnectPointsButtonPushed(app, event)
            app.Edges = []; Nc = numel(app.X); 
            Ns = numel(app.UIAxes.Children) - Nc;
            for i = 1:Ns
                delete(app.UIAxes.Children(1));
            end
            DT = delaunayTriangulation([app.X,app.Y]);
            Tri = DT.ConnectivityList; K = [2,3,1];
            app.Hull = convexHull(DT);
            for i = 1:size(Tri,1)
                for j = 1:3
                    k = K(j);
                    L = sort(Tri(i,[j,k]));
                    if (isempty(app.Edges))
                        app.Edges = [L;app.Edges]; 
                        plot(app.UIAxes, app.X(L),app.Y(L),'b');
                    elseif(~ismember(app.Edges,L,'rows'))
                        app.Edges = [L;app.Edges]; 
                        plot(app.UIAxes, app.X(L),app.Y(L),'b');
                    end
                end
            end
            title(app.UIAxes, '');
        end

        % Value changed function: DeleteUnwantedEdgesButton
        function DeleteUnwantedEdgesButtonValueChanged(app, event)
            title(app.UIAxes, 'Click on the edge to delete')
            cross = @(u,v) u(1)*v(2) - u(2)*v(1);
            dot   = @(u,v) u(1)*v(1) + u(2)*v(2);
            while(app.DeleteUnwantedEdgesButton.Value)
                roi = drawpoint(app.UIAxes); 
                xy = roi.Position; 
                delete(app.UIAxes.Children(1)); 
                idmin = 0; dmin = inf;
                for i = 1:size(app.Edges)
                    h = app.UIAxes.Children(i);
                    x = h.XData'; y = h.YData';
                    V = diff([x,y]); L = norm(V); 
                    v = [xy(1)-x(1), xy(2)-y(1)];
                    A = cross(V,v);
                    p = @(s) [x(1),y(1)] + s*V;
                    f = @(s) dot(xy - p(s), V);
                    s = fzero(f, 1);
                    d2 = abs(A)/L;
                    if(d2 < app.dl && 0 <= s && s <= 1)
                        if(d2 < dmin)
                            idmin = i; dmin = d2;
                        end
                    end
                end
                if(idmin>0)
                    delete(app.UIAxes.Children(idmin)); 
                    app.Edges(idmin,:) = []; 
                end
            end
            
        end

        % Value changed function: AddNewEdgesButton
        function AddNewEdgesButtonValueChanged(app, event)
            while(app.AddNewEdgesButton.Value)
                roi = drawpoint(app.UIAxes); 
                xy = roi.Position; delete(app.UIAxes.Children(1));
                D = sqrt((xy(1) - app.X).^2 + (xy(2) - app.Y).^2);
                [~, i] = min(D);
                roi = drawpoint(app.UIAxes); 
                xy = roi.Position; delete(app.UIAxes.Children(1));
                D = sqrt((xy(1) - app.X).^2 + (xy(2) - app.Y).^2);
                [~, j] = min(D); L = sort([i,j]); 
                app.Edges = [L;app.Edges]; 
                plot(app.UIAxes, app.X(L),app.Y(L),'b'); 
            end
        end

        % Value changed function: SupportTypeDropDown
        function SupportTypeDropDownValueChanged(app, event)
            app.SupportType = app.SupportTypeDropDown.Value;
        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            app.SupportOrient = app.OrientationDropDown.Value;
        end

        % Button pushed function: AddSupportButton
        function AddSupportButtonPushed(app, event)
            roi = drawpoint(app.UIAxes); 
            xy = roi.Position; delete(app.UIAxes.Children(1));
            D = hypot(xy(1) - app.X, xy(2) - app.Y);
            [~, i] = min(D);

            Rx = [-1.5, 1.5, 1.5, -1.5];
            Ry = [0, 0, 0.5, 0.5] - 2;
            R = [Rx', Ry'];
            
            type = 1; % pin 
            c = 'b';
            t = pi/2:2*pi/3:2*pi;
            T = [cos(t'), sin(t') - 1];
            if(app.SupportType == "Roller") 
                type = 2; c = 'g';
                t = linspace(0, 2*pi);
                T = 1.5*[cos(t'), sin(t')];
            end
            angle = find(app.SupportOrient == '^<v>')-1;
            ang = (angle)*pi/2;
            M = [cos(ang), sin(ang); -sin(ang), cos(ang)];
            r = hypot(app.dx, app.dy)/2;
            T = (T*r)*M + [app.X(i), app.Y(i)];
            R = (R*r)*M + [app.X(i), app.Y(i)];
            th = fill(app.UIAxes, T(:,1),T(:,2), c); 
            rh = fill(app.UIAxes, R(:,1),R(:,2), c); 
            Children = app.UIAxes.Children;
            app.UIAxes.Children = [Children(3:end);Children(1:2)];
            app.Supports = [app.Supports, ...
                [type, app.X(i), app.Y(i), angle]];
            support = Support([app.X(i), app.Y(i)], angle, type, [th, rh]);
            app.SupportObjects = [support, app.SupportObjects];
        end

        % Value changed function: LoadEditField
        function LoadEditFieldValueChanged(app, event)
            app.LoadValue = app.LoadEditField.Value;
        end

        % Value changed function: OrientatonEditField
        function OrientatonEditFieldValueChanged(app, event)
            app.LoadOrient = app.OrientatonEditField.Value;
        end

        % Button pushed function: AddLoadButton
        function AddLoadButtonPushed(app, event)
            drawArrow = @(x,y,l,t) quiver(app.UIAxes,x,y, l*cos(t), ....
                        l*sin(t), 0, "filled", 'k', 'LineWidth', 2, ...
                        MaxHeadSize = 1.5);
            roi = drawpoint(app.UIAxes); 
            xy = roi.Position; delete(app.UIAxes.Children(1));
            D = hypot(xy(1) - app.X, xy(2) - app.Y);
            [dmin, i] = min(D);
            if(dmin < app.dl)
                t = app.LoadOrient*pi/180; l = 3*app.dl;
                arrow = drawArrow(xy(1) , xy(2), l, t);
                app.Loads = [app.Loads, [app.X(i), app.Y(i), app.LoadValue, t]];
                load = Load(app.LoadValue, [app.X(i), app.Y(i)], t, arrow);
                app.LoadObjects = [app.LoadObjects , load];
                l = 4*app.dl;
                text(app.UIAxes, xy(1)+l*cos(t), xy(2)+l*sin(t), 0, ...
                    [num2str(app.LoadValue),'N'], ...
                    "HorizontalAlignment","center", ...
                    "VerticalAlignment","middle");
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            edges = app.Edges; edges = sort(edges, 2);
            % get rid of self connected point
            edges(edges(:,1) == edges(:,2),:) = []; 
            % get rid of duplicate members
            app.Edges = unique(edges,'rows');
            SolveTrusses([app.X,app.Y], app.Edges, app.Loads, ...
                        app.Supports, app.Hull);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.X  = [];             % Description
            app.Y  = [];             % Description
            app.dx  = 0;             % Description
            app.dy  = 0;             % Description
            app.Edges = [];          % Description
            app.Supports = {};       % Description
            app.SupportType = "";    % Description
            app.SupportOrient = "";  % Description
            app.SupportObjects = []; % Description
            app.Loads = {};          % Description
            app.LoadValue = 0;       % Description
            app.LoadOrient = 0;      % Description
            app.LoadObjects = [];    % Description
            app.ptID = 0;            % Description 
            app.done = false;        % Description
            app.UIAxes.XTick = [0,0.5,1];
            app.UIAxes.YTick = [0,0.5,1];
            app.UIAxes.XLim = [0,1];
            app.UIAxes.YLim = [0,1];
            cla(app.UIAxes)
            title(app.UIAxes, {'Reset: All variables initialized'})
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddSupportPanel = uipanel(app.LeftPanel);
            app.AddSupportPanel.Title = 'Add Support';
            app.SupportTypeDropDownLabel = uilabel(app.AddSupportPanel);
            app.SupportTypeDropDownLabel.Text = 'Support Type';
            app.SupportTypeDropDown = uidropdown(app.AddSupportPanel);
            app.SupportTypeDropDown.Items = {'Pin', 'Roller'};
            app.SupportTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SupportTypeDropDownValueChanged, true);
            app.SupportTypeDropDown.Value = 'Pin';
            app.OrientationDropDownLabel = uilabel(app.AddSupportPanel);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.AddSupportPanel);
            app.OrientationDropDown.Items = {'^', '<', ' v', '>'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Value = '^';
            app.AddSupportButton = uibutton(app.AddSupportPanel, 'push');
            app.AddSupportButton.ButtonPushedFcn = createCallbackFcn(app, @AddSupportButtonPushed, true);
            app.AddSupportButton.Text = 'Add Support';
            app.AddLoadPanel = uipanel(app.LeftPanel);
            app.AddLoadPanel.Title = 'Add Load';
            app.LoadEditFieldLabel = uilabel(app.AddLoadPanel);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.AddLoadPanel, 'numeric');
            app.LoadEditField.ValueChangedFcn = createCallbackFcn(app, @LoadEditFieldValueChanged, true);
            app.OrientatonEditFieldLabel = uilabel(app.AddLoadPanel);
            app.OrientatonEditFieldLabel.Text = 'Orientaton';
            app.OrientatonEditField = uieditfield(app.AddLoadPanel, 'numeric');
            app.OrientatonEditField.ValueChangedFcn = createCallbackFcn(app, @OrientatonEditFieldValueChanged, true);
            app.AddLoadButton = uibutton(app.AddLoadPanel, 'push');
            app.AddLoadButton.ButtonPushedFcn = createCallbackFcn(app, @AddLoadButtonPushed, true);
            app.AddLoadButton.Text = 'Add Load';
            app.ConnectPointsButton = uibutton(app.LeftPanel, 'push');
            app.ConnectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectPointsButtonPushed, true);
            app.ConnectPointsButton.Text = 'Connect Points';
            app.xTicksEditFieldLabel = uilabel(app.LeftPanel);
            app.xTicksEditFieldLabel.Text = 'xTicks';
            app.xTicksEditField = uieditfield(app.LeftPanel, 'text');
            app.xTicksEditField.ValueChangedFcn = createCallbackFcn(app, @xTicksEditFieldValueChanged, true);
            app.xTicksEditField.Value = '0,0.5,1';
            app.yTicksEditFieldLabel = uilabel(app.LeftPanel);
            app.yTicksEditFieldLabel.Text = 'yTicks';
            app.yTicksEditField = uieditfield(app.LeftPanel, 'text');
            app.yTicksEditField.ValueChangedFcn = createCallbackFcn(app, @yTicksEditFieldValueChanged, true);
            app.yTicksEditField.Value = '0,0.5,1';
            app.AddPointsButton = uibutton(app.LeftPanel, 'state');
            app.AddPointsButton.ValueChangedFcn = createCallbackFcn(app, @AddPointsButtonValueChanged, true);
            app.AddPointsButton.Text = 'Add Points';
            app.DeleteUnwantedEdgesButton = uibutton(app.LeftPanel, 'state');
            app.DeleteUnwantedEdgesButton.ValueChangedFcn = createCallbackFcn(app, @DeleteUnwantedEdgesButtonValueChanged, true);
            app.DeleteUnwantedEdgesButton.Text = 'Delete Unwanted Edges';
            app.AddNewEdgesButton = uibutton(app.LeftPanel, 'state');
            app.AddNewEdgesButton.ValueChangedFcn = createCallbackFcn(app, @AddNewEdgesButtonValueChanged, true);
            app.AddNewEdgesButton.Text = 'Add New Edges';
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Instructions appear here')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrussesSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end